classdef segmentationEvaluator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SegmentationEvaluatorUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        EraseButton                    matlab.ui.control.Button
        ConfirmLamp                    matlab.ui.control.Lamp
        ConfirmLampLabel               matlab.ui.control.Label
        DrawLamp                       matlab.ui.control.Lamp
        DrawLampLabel                  matlab.ui.control.Label
        ChooseCaseDropDown             matlab.ui.control.DropDown
        ChooseCaseLabel                matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        ConfirmButton                  matlab.ui.control.Button
        DrawButton                     matlab.ui.control.Button
        CenterPanel                    matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        RightPanel                     matlab.ui.container.Panel
        DiceScoreLabel                 matlab.ui.control.Label
        ResultsTextArea                matlab.ui.control.TextArea
        ResultsTextAreaLabel           matlab.ui.control.Label
        LevelLamp                      matlab.ui.control.Lamp
        LevelLampLabel                 matlab.ui.control.Label
        Gauge                          matlab.ui.control.Gauge
        GaugeLabel                     matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        % display image - this is different from the read image due to
        % overlays and such.
        imageToDisplay = imread("data/candidate_1_c3sag128.png");

        % the image data to run segmentation on
        imageToSegment = imread("data/candidate_1_c3sag128.png");   
        
        % mask corresponding to the image
        segmentMask = imread("data/label_1_c3sag128.png");

        % human-drawn segment
        humanSegmentMask

        % instruction above the image
        instructionTitle = "Label the brainstem - click on 'Draw' to start!";
        
        % Store the current case number.
        currentCase = "Case 1";

        % Handle for new segmentations
        newFH

        % Flag for draw or erase mode
        mode = "none";
    end
    
    methods (Access = private)
        
        function updateGauge(app, value)
            app.Gauge.Value = value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % When the App opens, load the first case image.
            app.humanSegmentMask = zeros('like', app.segmentMask);
            imshow(app.imageToDisplay, [], 'Parent', app.UIAxes);
            xlabel(app.UIAxes, "");
            ylabel(app.UIAxes, "");
            title(app.UIAxes, app.instructionTitle);

            app.LevelLamp.Color = [0.6350 0.0780 0.1840];
            app.Gauge.Value = 0;
            app.ResultsTextArea.Value = "";

            % Enable the draw boundary button
            app.DrawButton.Enable = 'on';
            app.DrawLamp.Color = "Green";
        end

        % Value changed function: ChooseCaseDropDown
        function ChooseCaseDropDownValueChanged(app, event)
            value = app.ChooseCaseDropDown.Value;

            if string(value) ~= string(app.currentCase)
                fig = app.SegmentationEvaluatorUIFigure;
                msg = 'Changing case will overwrite previous segmentations.';
                title = 'Confirm case change';
                selection = uiconfirm(fig, msg, title, ...
                            'Options',{'OK', 'Cancel'}, ...
                            'DefaultOption', 1, 'CancelOption', 2);
                if strcmp(selection, "OK")
                    switch value
                        case "Case 1"
                            app.imageToSegment = imread("data/candidate_1_c3sag128.png");
                            app.segmentMask = imread("data/label_1_c3sag128.png");                
                            app.instructionTitle = "Label the brainstem - click on 'Draw' to start!";
                        
                        case "Case 2"
                            app.imageToSegment = imread("data/candidate_2_c3sag150.png");
                            app.segmentMask = imread("data/label_2_c3sag150.png");
                            app.instructionTitle = "Label the tumor - click on 'Draw' to start!";
        
                        case "Case 3"
                            app.imageToSegment = imread("data/candidate_3_c7sag120.png");
                            app.segmentMask = imread("data/label_3_c7sag120.png");
                            app.instructionTitle = "Label the brainstem - click on 'Draw' to start!";
        
                        case "Case 4"
                            app.imageToSegment = imread("data/candidate_4_c7sag170.png");
                            app.segmentMask = imread("data/label_4_c7sag170.png");
                            app.instructionTitle = "Label the tumor - click on 'Draw' to start!";
        
                        case "Case 5"
                            app.imageToSegment = imread("data/candidate_5_c7sag130.png");
                            app.segmentMask = imread("data/label_5_c7sag130.png");
                            app.instructionTitle = "Label the brainstem - click on 'Draw' to start!";
                        otherwise
                            error("Invalid entry in case drop down - restart the App please!");
                    end
                    %app.imageToDisplay = uint8(app.imageToSegment.*255/max(app.imageToSegment(:)));
                    app.imageToDisplay = im2uint8(rescale(app.imageToSegment));
                    app.currentCase = value;
                    startupFcn(app);
                    app.DrawLamp.Color = "Green";
                else
                    app.ChooseCaseDropDown.Value = app.currentCase;
                end
            end
            
        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            app.instructionTitle = "Click and drag to draw a region on the image. Press 'Confirm' when you're satisfied!";
            title(app.UIAxes, app.instructionTitle);
            app.EraseButton.Enable = 'off';
            app.DrawButton.Enable = 'off';
            
            app.newFH = drawfreehand("Parent", app.UIAxes);
            app.ConfirmButton.Enable = 'on';
            app.ConfirmLamp.Color = "Green";

            app.mode = "draw";
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            if ~isempty(app.newFH)
                newMask = createMask(app.newFH);
                if strcmp(app.mode, "draw")
                    app.humanSegmentMask = app.humanSegmentMask | newMask;
                elseif strcmp(app.mode, "erase")
                    app.humanSegmentMask = app.humanSegmentMask & ~newMask;
                end

                diceCoeff = 2 * sum(app.humanSegmentMask & app.segmentMask) / (sum(app.segmentMask) + sum(app.humanSegmentMask));
                
                app.updateGauge(diceCoeff * 100);

                app.imageToDisplay = labeloverlay(im2uint8(rescale(app.imageToSegment)), uint8(app.humanSegmentMask));
                imshow(app.imageToDisplay, [], 'Parent', app.UIAxes);

                if app.Gauge.Value >= 85
                    app.LevelLamp.Color = [0.4660 0.6740 0.1880];
                    status = sprintf("Great job, your score is %f, what next?", diceCoeff*100);
                    app.ResultsTextArea.Value = status;
                elseif app.Gauge.Value < 85 && app.Gauge.Value >= 70
                    app.LevelLamp.Color = [0.9290 0.6940 0.1250];
                    status = sprintf("Good progress, your score is %f, can you do better?", diceCoeff*100);
                    app.ResultsTextArea.Value = status;
                else
                    app.LevelLamp.Color = [0.6350 0.0780 0.1840];
                    status = sprintf("There's some way to go: your score is %f, try again?", diceCoeff*100);
                    app.ResultsTextArea.Value = status;
                end

                delete(app.newFH)
                app.newFH = [];
                app.EraseButton.Enable = 'on';
                app.ConfirmButton.Enable = 'off';
                app.DrawButton.Enable = 'on';
                app.ConfirmLamp.Color = "Red";
                app.instructionTitle = "Click 'Draw' or 'Erase' to make more changes!";
                title(app.UIAxes, app.instructionTitle);
            else
                app.ConfirmButton.Enable = 'off';
                app.ConfirmLamp.Color = "Red";
            end
            app.mode = "none";
        end

        % Button pushed function: EraseButton
        function EraseButtonPushed(app, event)
            app.instructionTitle = "Click and drag to erase a region on the image. Press 'Confirm' when you're satisfied!";
            title(app.UIAxes, app.instructionTitle);
            app.DrawButton.Enable = 'off';
            
            app.newFH = drawfreehand("Parent", app.UIAxes, "Color", [0.8500 0.3250 0.0980]);
            app.ConfirmButton.Enable = 'on';
            app.ConfirmLamp.Color = "Green";

            app.mode = "erase";
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            fig = app.SegmentationEvaluatorUIFigure;
            msg = 'Resetting will delete all existing segmentations.';
            title = 'Confirm reset';
            selection = uiconfirm(fig, msg, title, ...
                        'Options',{'OK', 'Cancel'}, ...
                        'DefaultOption', 1, 'CancelOption', 2);
            if strcmp(selection, "OK")
                app.imageToSegment = imread("data/candidate_1_c3sag128.png");
                app.imageToDisplay = app.imageToSegment;
                app.segmentMask = imread("data/label_1_c3sag128.png");
                app.humanSegmentMask = zeros("like", app.segmentMask);
                app.EraseButton.Enable = 'off';

                app.instructionTitle = "Label the brainstem - click on 'Draw' to start!";
                app.ChooseCaseDropDown.Value = "Case 1";
                startupFcn(app);
                app.DrawLamp.Color = "Green";
                app.ResultsTextArea.Value = "";
                app.LevelLamp.Color = [0.6350 0.0780 0.1840];
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SegmentationEvaluatorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SegmentationEvaluatorUIFigure = uifigure('Visible', 'off');
            app.SegmentationEvaluatorUIFigure.Name = 'Segmentation Evaluator';
            app.SegmentationEvaluatorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SegmentationEvaluatorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DrawButton = uibutton(app.LeftPanel, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Enable = 'off';
            app.DrawButton.Text = 'Draw';
            app.ConfirmButton = uibutton(app.LeftPanel, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Enable = 'off';
            app.ConfirmButton.Text = 'Confirm ';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ChooseCaseLabel = uilabel(app.LeftPanel);
            app.ChooseCaseLabel.Text = 'Choose Case:';
            app.ChooseCaseDropDown = uidropdown(app.LeftPanel);
            app.ChooseCaseDropDown.Items = {'Case 1', 'Case 2', 'Case 3', 'Case 4', 'Case 5'};
            app.ChooseCaseDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseCaseDropDownValueChanged, true);
            app.ChooseCaseDropDown.Placeholder = 'empty';
            app.ChooseCaseDropDown.Value = 'Case 1';
            app.DrawLampLabel = uilabel(app.LeftPanel);
            app.DrawLampLabel.Text = 'Draw';
            app.DrawLamp = uilamp(app.LeftPanel);
            app.ConfirmLampLabel = uilabel(app.LeftPanel);
            app.ConfirmLampLabel.Text = 'Confirm';
            app.ConfirmLamp = uilamp(app.LeftPanel);
            app.EraseButton = uibutton(app.LeftPanel, 'push');
            app.EraseButton.ButtonPushedFcn = createCallbackFcn(app, @EraseButtonPushed, true);
            app.EraseButton.Enable = 'off';
            app.EraseButton.Text = 'Erase';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.GaugeLabel = uilabel(app.RightPanel);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.RightPanel, 'circular');
            app.LevelLampLabel = uilabel(app.RightPanel);
            app.LevelLampLabel.Text = 'Level: ';
            app.LevelLamp = uilamp(app.RightPanel);
            app.ResultsTextAreaLabel = uilabel(app.RightPanel);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.RightPanel);
            app.DiceScoreLabel = uilabel(app.RightPanel);
            app.DiceScoreLabel.Text = 'Dice Score';
            % Show the figure after all components are created
            app.SegmentationEvaluatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = segmentationEvaluator

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SegmentationEvaluatorUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.SegmentationEvaluatorUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SegmentationEvaluatorUIFigure)
        end
    end
end